classdef SpeedControllerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NumberOfNodes              matlab.ui.container.Panel
        Node4EditField             matlab.ui.control.NumericEditField
        Node4EditFieldLabel        matlab.ui.control.Label
        EditField_4                matlab.ui.control.NumericEditField
        Node3EditField             matlab.ui.control.NumericEditField
        Node3EditFieldLabel        matlab.ui.control.Label
        EditField_3                matlab.ui.control.NumericEditField
        Node2EditField             matlab.ui.control.NumericEditField
        Node2EditFieldLabel        matlab.ui.control.Label
        EditField_2                matlab.ui.control.NumericEditField
        SpeedLabel                 matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        Node1EditField             matlab.ui.control.NumericEditField
        Node1EditFieldLabel        matlab.ui.control.Label
        StrideLabel                matlab.ui.control.Label
        NumberofNodesSpinner       matlab.ui.control.Spinner
        NumberofNodesSpinnerLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        WalkModesPanel             matlab.ui.container.Panel
        RunningButton              matlab.ui.control.Button
        LockedButton               matlab.ui.control.Button
        ScopeSelectRecordPanel     matlab.ui.container.Panel
        tStance_avg                matlab.ui.control.Label
        tStride_avg                matlab.ui.control.Label
        StrideStateR               matlab.ui.control.Label
        AverageStanceTimeLabel     matlab.ui.control.Label
        AverageStrideTimeLabel     matlab.ui.control.Label
        StrideStateRightLabel      matlab.ui.control.Label
        PreExperimentPanel         matlab.ui.container.Panel
        GetMassButton              matlab.ui.control.Button
        ZeroTreadmillButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        tg              % target
        fsc             % filescope 
        % modes contains info about the discrete modes (states) we may be in
        % e.g. exoModes.prep.ankle tells which prep mode (REST/SETZERO/WALK) we're in at the ankle
        exoModes
        
        % housekeeping properties
        n_resets        % number of times IL reset (related to SPT?)
        dateTimeStart
        dir = cd
        saveDir = 'C:\Users\Vicon-OEM\Documents\RMM\repos\katee\rawData'
        saveFile = 'FileScope.dat'
        scopes = [];            % list of scopes
        
        % cma object contains CMA-ES (hyper)parameters
        % e.g. app.cma.lambda is the number of conditions per generation
        %cma
        
        % assistance object contains knee and ank min, max, mean, static, current torque parameters
        % e.g. app.assistance.knee.min is a [7x1] matrix with the min vals for the 7 parameters of knee assist
        %assistance
        
        % gains object contains all the Kp, Kd, Kl, delay parameters for ank and knee
        % e.g. app.gains.kneeFlex.Kp is the proportional gain on knee flexion
        %gains
        
        % subjInfo contains information about the subject (subjNo, dayNo, age, weight, shankNo)
        subjInfo
        
        % tm contains info about treadmill (vel, SPTState, GRFOffsets, params)
        tm
        
        % slxConstants contains values of enumerated constants from slx model (e.g.
        % WALKMODE=2 in the slx controller, so when the mode signal is 2, we
        % know that it is walk mode)
        slxConstants
        
        NodePctStride %Percent stride on gui
        NodeSpeed %speed on gui
        SetNodePct %Percent stride sent to simulink
        SetNodeSpeed %Speed sent to simulink
        NumNodes
        % timers
        timer_update_gui    % updates states on GUI at every DT_TIMERGUIUPDATE seconds
        timer_debug         % prints state of controller signals to matlab command window
    end
    
    properties (Constant)
        % time constants (s)
        %DT_TIMERGRFCALIBRATE = 0.05;
        DT_TIMERGUIUPDATE = 0.2;
        DT_TIMERDEBUG = 0.05;
        
        % ~~~ enumeration ~~~
        % general purpose
        OFF = 0;
        ON = 1;
        
        % joints and DoFs
        % ANK = 11; % Ankle joint
        % KNEE = 12; % Knee joint
        % KNFL = 13; % knee flexion
        % KNEX = 14; % knee extension
        % 
        % % cma or static modes used for ankle and knee (state_cma_joint)
        % MODE_CMA = 20;
        % MODE_STATIC = 21;
        % 
        % % prep mode for ankle and knee (state_prep_joint)
        % MODE_REST = 30;
        % MODE_SETZERO = 31;
        % MODE_WALK = 32;
        % 
        % % walk mode for ankle and knee (state_walk_joint)
        % MODE_ZEROTORQUE = 40;
        % MODE_TORQUE = 41;
        % 
        % ~~~ \enumeration ~~~
    end

    methods (Access = private)
        
        function update_constants(app)
            basePath = 'Sensor Read and Process/GUI/';
            app.slxConstants.LOCK       = getsignal(app.tg, [basePath, 'LOCK']);
            app.slxConstants.RUN      = getsignal(app.tg, [basePath, 'RUN']);
            app.slxConstants.REST       = getsignal(app.tg, [basePath, 'REST']);
            app.slxConstants.SETZERO    = getsignal(app.tg, [basePath, 'SETZERO']);
            app.slxConstants.SWING      = getsignal(app.tg, [basePath, 'SWING']);
            app.slxConstants.STANCE     = getsignal(app.tg, [basePath, 'STANCE']);
        end
    
        
        function send_Nodes(app)
            %path = 'Sensor Read and Process/GUI/';
            
                switch app.NumNodes %Check the number of the nodes and set the speed
                    case 1
                        app.SetNodePct.Node1 = app.NodePctStride.Node1;
                        app.SetNodeSpeed.Node1 = app.NodeSpeed.Node1;
                        app.SetNodePct.Node2 = 0;
                        app.SetNodeSpeed.Node2 = 0;
                        app.SetNodePct.Node3 = 0;
                        app.SetNodeSpeed.Node3 = 0;
                        app.SetNodePct.Node4 = 0;
                        app.SetNodeSpeed.Node4 = 0;
                    case 2
                        app.SetNodePct.Node1 = app.NodePctStride.Node1;
                        app.SetNodeSpeed.Node1 = app.NodeSpeed.Node1;
                        app.SetNodePct.Node2 = app.NodePctStride.Node2;
                        app.SetNodeSpeed.Node2 = app.NodeSpeed.Node2;
                        app.SetNodePct.Node3 = 0;
                        app.SetNodeSpeed.Node3 = 0;
                        app.SetNodePct.Node4 = 0;
                        app.SetNodeSpeed.Node4 = 0;
                    case 3
                        app.SetNodePct.Node1 = app.NodePctStride.Node1;
                        app.SetNodeSpeed.Node1 = app.NodeSpeed.Node1;
                        app.SetNodePct.Node2 = app.NodePctStride.Node2;
                        app.SetNodeSpeed.Node2 = app.NodeSpeed.Node2;
                        app.SetNodePct.Node3 = app.NodePctStride.Node3;
                        app.SetNodeSpeed.Node3 = app.NodeSpeed.Node3;
                        app.SetNodePct.Node4 = 0;
                        app.SetNodeSpeed.Node4 = 0;
                    case 4
                        app.SetNodePct.Node1 = app.NodePctStride.Node1;
                        app.SetNodeSpeed.Node1 = app.NodeSpeed.Node1;
                        app.SetNodePct.Node2 = app.NodePctStride.Node2;
                        app.SetNodeSpeed.Node2 = app.NodeSpeed.Node2;
                        app.SetNodePct.Node3 = app.NodePctStride.Node3;
                        app.SetNodeSpeed.Node3 = app.NodeSpeed.Node3;
                        app.SetNodePct.Node4 = app.NodePctStride.Node4;
                        app.SetNodeSpeed.Node4 = app.NodeSpeed.Node4;
                        
                end
                setparam(app.tg,'Sensor Read and Process/GUI/NODE1P','Value',app.SetNodePct.Node1); 
                setparam(app.tg,'Sensor Read and Process/GUI/NODE1S','Value',app.SetNodeSpeed.Node1);
                setparam(app.tg,'Sensor Read and Process/GUI/NODE2P','Value',app.SetNodePct.Node2); 
                setparam(app.tg,'Sensor Read and Process/GUI/NODE2S','Value',app.SetNodeSpeed.Node2);
                setparam(app.tg,'Sensor Read and Process/GUI/NODE3P','Value',app.SetNodePct.Node3); 
                setparam(app.tg,'Sensor Read and Process/GUI/NODE3S','Value',app.SetNodeSpeed.Node3);
                setparam(app.tg,'Sensor Read and Process/GUI/NODE4P','Value',app.SetNodePct.Node4); 
                setparam(app.tg,'Sensor Read and Process/GUI/NODE4S','Value',app.SetNodeSpeed.Node4);
            app.updateGraph();
        end
        
        function updateGraph(app)
            perc = 1:100;
            switch app.NumNodes
                case 1
                    xaxis = app.SetNodePct.Node1;
                    yaxis = app.SetNodeSpeed.Node1;
                case 2
                    xaxis = [app.SetNodePct.Node1,app.SetNodePct.Node2];
                    yaxis = [app.SetNodeSpeed.Node1,app.SetNodeSpeed.Node2];
                case 3
                    xaxis = [app.SetNodePct.Node1,app.SetNodePct.Node2,app.SetNodePct.Node3];
                    yaxis = [app.SetNodeSpeed.Node1,app.SetNodeSpeed.Node2,app.SetNodeSpeed.Node3];
                case 4
                    xaxis = [app.SetNodePct.Node1,app.SetNodePct.Node2,app.SetNodePct.Node3,app.SetNodePct.Node4];
                    yaxis = [app.SetNodeSpeed.Node1,app.SetNodeSpeed.Node2,app.SetNodeSpeed.Node3,app.SetNodeSpeed.Node4];
            end
            if app.NumNodes == 1
                plot(app.UIAxes,xaxis,yaxis,'o');
                %yline(app.UIAxes,yaxis); <- did not work
            else 
                speed = pchip(xaxis,yaxis,perc);    
                plot(app.UIAxes,xaxis,yaxis,'o');
                hold(app.UIAxes,'on');
                plot(app.UIAxes,perc,speed);
                hold(app.UIAxes,'off');
            end
        end
        
        function setColor(app)
            switch app.NumNodes
                case 1
                    app.Node1EditField.FontColor = [0 0 0];
                    app.EditField.FontColor = [0 0 0];
                    app.Node2EditField.FontColor = [0.7 0.7 0.7];
                    app.EditField_2.FontColor = [0.7 0.7 0.7];
                    app.Node3EditField.FontColor = [0.7 0.7 0.7];
                    app.EditField_3.FontColor = [0.7 0.7 0.7];
                    app.Node4EditField.FontColor = [0.7 0.7 0.7];
                    app.EditField_4.FontColor = [0.7 0.7 0.7];
                case 2
                    app.Node1EditField.FontColor = [0 0 0];
                    app.EditField.FontColor = [0 0 0];
                    app.Node2EditField.FontColor = [0 0 0];
                    app.EditField_2.FontColor = [0 0 0];
                    app.Node3EditField.FontColor = [0.7 0.7 0.7];
                    app.EditField_3.FontColor = [0.7 0.7 0.7];
                    app.Node4EditField.FontColor = [0.7 0.7 0.7];
                    app.EditField_4.FontColor = [0.7 0.7 0.7];
                case 3
                    app.Node1EditField.FontColor = [0 0 0];
                    app.EditField.FontColor = [0 0 0];
                    app.Node2EditField.FontColor = [0 0 0];
                    app.EditField_2.FontColor = [0 0 0];
                    app.Node3EditField.FontColor = [0 0 0];
                    app.EditField_3.FontColor = [0 0 0];
                    app.Node4EditField.FontColor = [0.7 0.7 0.7];
                    app.EditField_4.FontColor = [0.7 0.7 0.7];
                case 4
                    app.Node1EditField.FontColor = [0 0 0];
                    app.EditField.FontColor = [0 0 0];
                    app.Node2EditField.FontColor = [0 0 0];
                    app.EditField_2.FontColor = [0 0 0];
                    app.Node3EditField.FontColor = [0 0 0];
                    app.EditField_3.FontColor = [0 0 0];
                    app.Node4EditField.FontColor = [0 0 0];
                    app.EditField_4.FontColor = [0 0 0];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            format compact
            
            % start simulink. time controller build time
            app.tg = SimulinkRealTime.target;
            %app.fsc = getscope(app.tg, 5);
            
            
            % create timers
            app.timer_update_gui = timer('TimerFcn',@app.update_gui, ...
                 'Period', app.DT_TIMERGUIUPDATE , 'ExecutionMode','fixedRate','BusyMode','drop', 'Name', 'timer_update_gui');
            app.timer_debug = timer('TimerFcn',@app.print_status, ...
                'Period', app.DT_TIMERDEBUG , 'ExecutionMode','fixedRate','BusyMode','queue', 'Name', 'timer_debug');
            
            % update constants from slx file for state determination
            app.update_constants();
            % housekeeping
            app.dateTimeStart = datetime('now');

            app.NumNodes = 4;
            app.SetNodePct.Node1 = 25;
            app.SetNodePct.Node2 = 50;
            app.SetNodePct.Node3 = 75;
            app.SetNodePct.Node4 = 100;
            app.NodePctStride.Node1 = 25;
            app.NodePctStride.Node2 = 50;
            app.NodePctStride.Node3 = 75;
            app.NodePctStride.Node4 = 100;
            app.SetNodeSpeed.Node1 = 0.5;
            app.SetNodeSpeed.Node2 = 1.1;
            app.SetNodeSpeed.Node3 = 1.3;
            app.SetNodeSpeed.Node4 = 1;
            app.NodeSpeed.Node1 = 0.5;
            app.NodeSpeed.Node2 = 1.1;
            app.NodeSpeed.Node3 = 1.3;
            app.NodeSpeed.Node4 = 1;
            
            app.updateGraph();
        end

        % Button pushed function: ZeroTreadmillButton
        function ZeroTreadmillButtonPushed(app, event)
            setparam(app.tg,'Sensor Read and Process/GUI/BTzeroLeft','Value',1);
            setparam(app.tg,'Sensor Read and Process/GUI/BTzeroRight','Value',1);
            pause(0.01);
            setparam(app.tg,'Sensor Read and Process/GUI/BTzeroLeft','Value',0);
            setparam(app.tg,'Sensor Read and Process/GUI/BTzeroRight','Value',0);
            
            app.ZeroTreadmillButton.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: GetMassButton
        function GetMassButtonPushed(app, event)
            setparam(app.tg,'Sensor Read and Process/GUI/BTmeasureWeight','Value',1);
            
            % Measure weight for predefined number of samples and add to vector
            nWeightSamples = 500;
            weightMemory = zeros(nWeightSamples,1);
            counter = 1;
            while counter <= nWeightSamples
                subjWeight = getsignal(app.tg, 'Sensor Read and Process/measureSubjWeight');
                weightMemory(counter) = subjWeight;
                counter = counter + 1;
            end
            
            % Determine subject mass
            app.subjInfo.weight = mean(weightMemory) / 9.81; % [kg]
            pause(0.01);
            setparam(app.tg,'Sensor Read and Process/GUI/BTmeasureWeight','Value',0)
            disp(['Subject mass: ', num2str(app.subjInfo.weight), ' kg']);
            %setparam(app.tg, 'Sensor Read and Process/CMAES/Mass','Value', app.subjInfo.weight);
            
            app.GetMassButton.BackgroundColor = [0.96, 0.96, 0.96];
        end

        % Button pushed function: LockedButton
        function LockedButtonPushed(app, event)
            %setparam(app.tg,'Sensor Read and Process/GUI/Lock','Value',1);
            %setparam(app.tg,'Sensor Read and Process/GUI/Running','Value',0);
            app.LockedButton.BackgroundColor = [0.99,1.00,0.80];
            app.RunningButton.BackgroundColor = [1, 1, 1];
            setparam(app.tg,'Sensor Read and Process/GUI/BTLock','Value',1);
            pause(0.01);
            setparam(app.tg,'Sensor Read and Process/GUI/BTLock','Value',0);
        end

        % Button pushed function: RunningButton
        function RunningButtonPushed(app, event)
            %setparam(app.tg,'Sensor Read and Process/GUI/Lock','Value',0);
            app.LockedButton.BackgroundColor = [1, 1, 1];
            app.RunningButton.BackgroundColor = [0.99,1.00,0.80];
            setparam(app.tg,'Sensor Read and Process/GUI/BTRun','Value',1);
            pause(0.01);
            setparam(app.tg,'Sensor Read and Process/GUI/BTRun','Value',0);
        end

        % Value changed function: NumberofNodesSpinner
        function NumberofNodesSpinnerValueChanged(app, event)
            app.NumNodes = app.NumberofNodesSpinner.Value;
            app.send_Nodes();
            app.setColor();
            setparam(app.tg,'Sensor Read and Process/GUI/NUMNODES','Value',app.NumNodes);
        end

        % Value changed function: Node1EditField
        function Node1EditFieldValueChanged(app, event)
            app.NodePctStride.Node1  = app.Node1EditField.Value;
            app.send_Nodes();
            % setparam(app.tg,'Sensor Read and Process/GUI/NODE1P','Value',app.NodePctStride.Node1);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.NodeSpeed.Node1 = app.EditField.Value;
            app.send_Nodes();
            %setparam(app.tg,'Sensor Read and Process/GUI/NODE1S','Value',app.NodeSpeed.Node1);
        end

        % Value changed function: EditField_2
        function EditFieldValueChanged2(app, event)
            app.NodeSpeed.Node2 = app.EditField_2.Value;
            app.send_Nodes();
        end

        % Value changed function: Node2EditField
        function Node2EditFieldValueChanged(app, event)
            app.NodePctStride.Node2 = app.Node2EditField.Value;
            app.send_Nodes();
        end

        % Value changed function: Node3EditField
        function Node3EditFieldValueChanged(app, event)
            app.NodePctStride.Node3 = app.Node3EditField.Value;
            app.send_Nodes();
        end

        % Value changed function: Node4EditField
        function Node4EditFieldValueChanged(app, event)
            app.NodePctStride.Node4 = app.Node4EditField.Value;
            app.send_Nodes();
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.NodeSpeed.Node3 = app.EditField_3.Value;
            app.send_Nodes();
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.NodeSpeed.Node4 = app.EditField_4.Value;
            app.send_Nodes();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PreExperimentPanel = uipanel(app.UIFigure);
            app.PreExperimentPanel.Title = 'Pre-Experiment';
            app.ZeroTreadmillButton = uibutton(app.PreExperimentPanel, 'push');
            app.ZeroTreadmillButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroTreadmillButtonPushed, true);
            app.ZeroTreadmillButton.Text = 'Zero Treadmill';
            app.GetMassButton = uibutton(app.PreExperimentPanel, 'push');
            app.GetMassButton.ButtonPushedFcn = createCallbackFcn(app, @GetMassButtonPushed, true);
            app.GetMassButton.Text = 'Get Mass';
            app.ScopeSelectRecordPanel = uipanel(app.UIFigure);
            app.ScopeSelectRecordPanel.Title = 'Scope Select/Record';
            app.StrideStateRightLabel = uilabel(app.ScopeSelectRecordPanel);
            app.StrideStateRightLabel.Text = 'Stride State Right';
            app.AverageStrideTimeLabel = uilabel(app.ScopeSelectRecordPanel);
            app.AverageStrideTimeLabel.Text = 'Average Stride Time';
            app.AverageStanceTimeLabel = uilabel(app.ScopeSelectRecordPanel);
            app.AverageStanceTimeLabel.Text = 'Average Stance Time';
            app.StrideStateR = uilabel(app.ScopeSelectRecordPanel);
            app.StrideStateR.Text = '1';
            app.tStride_avg = uilabel(app.ScopeSelectRecordPanel);
            app.tStride_avg.Text = '1';
            app.tStance_avg = uilabel(app.ScopeSelectRecordPanel);
            app.tStance_avg.Text = '1';
            app.WalkModesPanel = uipanel(app.UIFigure);
            app.WalkModesPanel.Title = 'Walk Modes';
            app.LockedButton = uibutton(app.WalkModesPanel, 'push');
            app.LockedButton.ButtonPushedFcn = createCallbackFcn(app, @LockedButtonPushed, true);
            app.LockedButton.Text = 'Locked';
            app.RunningButton = uibutton(app.WalkModesPanel, 'push');
            app.RunningButton.ButtonPushedFcn = createCallbackFcn(app, @RunningButtonPushed, true);
            app.RunningButton.Text = 'Running';
            app.NumberOfNodes = uipanel(app.UIFigure);
            app.NumberOfNodes.Title = 'Panel';
            app.UIAxes = uiaxes(app.NumberOfNodes);
            title(app.UIAxes, '% Strid vs Speed')
            xlabel(app.UIAxes, '% Stride')
            ylabel(app.UIAxes, 'Speed')
            zlabel(app.UIAxes, 'Z')
            app.NumberofNodesSpinnerLabel = uilabel(app.NumberOfNodes);
            app.NumberofNodesSpinnerLabel.Text = 'Number of Nodes';
            app.NumberofNodesSpinner = uispinner(app.NumberOfNodes);
            app.NumberofNodesSpinner.Limits = [1 4];
            app.NumberofNodesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofNodesSpinnerValueChanged, true);
            app.NumberofNodesSpinner.Value = 4;
            app.StrideLabel = uilabel(app.NumberOfNodes);
            app.StrideLabel.Text = '% Stride';
            app.Node1EditFieldLabel = uilabel(app.NumberOfNodes);
            app.Node1EditFieldLabel.Text = 'Node 1';
            app.Node1EditField = uieditfield(app.NumberOfNodes, 'numeric');
            app.Node1EditField.Limits = [0 100];
            app.Node1EditField.ValueChangedFcn = createCallbackFcn(app, @Node1EditFieldValueChanged, true);
            app.Node1EditField.Value = 25;
            app.EditField = uieditfield(app.NumberOfNodes, 'numeric');
            app.EditField.Limits = [0 1.5];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 0.5;
            app.SpeedLabel = uilabel(app.NumberOfNodes);
            app.SpeedLabel.Text = 'Speed';
            app.EditField_2 = uieditfield(app.NumberOfNodes, 'numeric');
            app.EditField_2.Limits = [0 1.5];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged2, true);
            app.EditField_2.Value = 1.1;
            app.Node2EditFieldLabel = uilabel(app.NumberOfNodes);
            app.Node2EditFieldLabel.Text = 'Node 2';
            app.Node2EditField = uieditfield(app.NumberOfNodes, 'numeric');
            app.Node2EditField.Limits = [0 100];
            app.Node2EditField.ValueChangedFcn = createCallbackFcn(app, @Node2EditFieldValueChanged, true);
            app.Node2EditField.Value = 50;
            app.EditField_3 = uieditfield(app.NumberOfNodes, 'numeric');
            app.EditField_3.Limits = [0 1.5];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 1.3;
            app.Node3EditFieldLabel = uilabel(app.NumberOfNodes);
            app.Node3EditFieldLabel.Text = 'Node 3';
            app.Node3EditField = uieditfield(app.NumberOfNodes, 'numeric');
            app.Node3EditField.Limits = [0 100];
            app.Node3EditField.ValueChangedFcn = createCallbackFcn(app, @Node3EditFieldValueChanged, true);
            app.Node3EditField.Value = 75;
            app.EditField_4 = uieditfield(app.NumberOfNodes, 'numeric');
            app.EditField_4.Limits = [0 1.5];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 1;
            app.Node4EditFieldLabel = uilabel(app.NumberOfNodes);
            app.Node4EditFieldLabel.Text = 'Node 4';
            app.Node4EditField = uieditfield(app.NumberOfNodes, 'numeric');
            app.Node4EditField.Limits = [0 100];
            app.Node4EditField.ValueChangedFcn = createCallbackFcn(app, @Node4EditFieldValueChanged, true);
            app.Node4EditField.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpeedControllerGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end